classdef TicTacToe_Game < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        GridLayout   matlab.ui.container.GridLayout
        Label2       matlab.ui.control.Label
        Label        matlab.ui.control.Label
        ResetButton  matlab.ui.control.Button
        Button_9     matlab.ui.control.Button
        Button_8     matlab.ui.control.Button
        Button_7     matlab.ui.control.Button
        Button_6     matlab.ui.control.Button
        Button_5     matlab.ui.control.Button
        Button_4     matlab.ui.control.Button
        Button_3     matlab.ui.control.Button
        Button_2     matlab.ui.control.Button
        Button_1     matlab.ui.control.Button
    end


    properties (Access = private)
    currentPlayer = "X"; % Start with Player X
    board = repmat(" ", 3, 3); % 3x3 grid to track moves
    GameActive = false; % Flag to indicate if the game is over
end
    
    methods (Access = private)
          function declareWinner(app)
    app.Label.Text = sprintf("Player '%s' Wins!", app.currentPlayer);
    app.Label2.Text = sprintf("Hurray 🎉")
    app.GameActive = true;
        end
    
         function checkWinner(app)

    % Check rows for a winner
    for i = 1:3
       if app.board(i,1) == app.currentPlayer && app.board(i,2) == app.currentPlayer && app.board(i,3) == app.currentPlayer
           app.declareWinner();
            return;
       end
    end

    % Check columns for a winner
    for i = 1:3
        if app.board(1,i) == app.currentPlayer && app.board(2,i) == app.currentPlayer && app.board(3,i) == app.currentPlayer
           app.declareWinner();
            return;
        end
    end

    % Check diagonals for a winner
    if app.board(1,1) == app.currentPlayer && app.board(2,2) == app.currentPlayer && app.board(3,3) == app.currentPlayer
        app.declareWinner();
        return;
    end

    if app.board(1,3) == app.currentPlayer && app.board(2,2) == app.currentPlayer && app.board(3,1) == app.currentPlayer
        app.declareWinner();
        return;
    end

    % Check for a draw
    if all(app.board ~= " ", 'all')
        app.Label.Text = "Match Drawn...";
        app.GameActive = true;
    end
end


         function switchPlayer(app)
    if  app.currentPlayer == "X"
        app.currentPlayer = "O";
    else
        app.currentPlayer = "X";
    end
    app.Label.Text = sprintf("Player %s's Turn", app.currentPlayer);            
         end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            if ~app.GameActive && app.board(1,1) == " "
        app.board(1,1) = app.currentPlayer;
        if app.currentPlayer == "X"
            app.Button_1.FontColor = [1.00,0.70,0.70];
        else
            app.Button_1.FontColor = [0.35,0.70,1.00];
        end
        app.Button_1.Text = app.currentPlayer;
        app.checkWinner();
        if ~app.GameActive
           app.switchPlayer();
        end
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
             if ~app.GameActive && app.board(1,2) == " "
        app.board(1,2) = app.currentPlayer;
        if app.currentPlayer == "X"
            app.Button_2.FontColor = [1.00,0.70,0.70];
        else
            app.Button_2.FontColor = [0.35,0.70,1.00];
        end
        app.Button_2.Text = app.currentPlayer;
        app.checkWinner();
         if ~app.GameActive
           app.switchPlayer();
         end
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
             if ~app.GameActive && app.board(1,3) == " "
        app.board(1,3) = app.currentPlayer;
        if app.currentPlayer == "X"
            app.Button_3.FontColor = [1.00,0.70,0.70];
        else
            app.Button_3.FontColor = [0.35,0.70,1.00];
        end
        app.Button_3.Text = app.currentPlayer;
        app.checkWinner();
         if ~app.GameActive
           app.switchPlayer();
        end
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
             if ~app.GameActive && app.board(2,1) == " "
        app.board(2,1) = app.currentPlayer;
        if app.currentPlayer == "X"
            app.Button_4.FontColor = [1.00,0.70,0.70];
        else
            app.Button_4.FontColor = [0.35,0.70,1.00];
        end
        app.Button_4.Text = app.currentPlayer;
        app.checkWinner();
        if ~app.GameActive
           app.switchPlayer();
        end
            end
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            disp(size(app.board));
            if ~app.GameActive && app.board(2,2) == " "
        app.board(2,2) = app.currentPlayer;
        if app.currentPlayer == "X"
            app.Button_5.FontColor = [1.00,0.70,0.70];
        else
            app.Button_5.FontColor = [0.35,0.70,1.00];
        end
        app.Button_5.Text = app.currentPlayer;
        app.checkWinner();
         if ~app.GameActive
           app.switchPlayer();
         end 
            end
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
             if ~app.GameActive && app.board(2,3) == " "
        app.board(2,3) = app.currentPlayer;
        if app.currentPlayer == "X"
            app.Button_6.FontColor = [1.00,0.70,0.70];
        else
            app.Button_6.FontColor = [0.35,0.70,1.00];
        end
        app.Button_6.Text = app.currentPlayer;
        app.checkWinner();
         if ~app.GameActive
           app.switchPlayer();
         end 
            end
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
             if ~app.GameActive && app.board(3,1) == " "
        app.board(3,1) = app.currentPlayer;
        if app.currentPlayer == "X"
            app.Button_7.FontColor = [1.00,0.70,0.70];
        else
            app.Button_7.FontColor = [0.35,0.70,1.00];
        end
        app.Button_7.Text = app.currentPlayer;
        app.checkWinner();
       if ~app.GameActive
           app.switchPlayer();
       end 
            end
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
             if ~app.GameActive && app.board(3,2) == " "
        app.board(3,2) = app.currentPlayer;
        if app.currentPlayer == "X"
            app.Button_8.FontColor = [1.00,0.70,0.70];
        else
            app.Button_8.FontColor = [0.35,0.70,1.00];
        end
        app.Button_8.Text = app.currentPlayer;
        app.checkWinner();
        if ~app.GameActive
           app.switchPlayer();
        end
            end
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
             if ~app.GameActive && app.board(3,3) == " "
        app.board(3,3) = app.currentPlayer;
        if app.currentPlayer == "X"
            app.Button_9.FontColor = [1.00,0.70,0.70];
        else
            app.Button_9.FontColor = [0.35,0.70,1.00];
        end
        app.Button_9.Text = app.currentPlayer;
        app.checkWinner();
        if ~app.GameActive
           app.switchPlayer();
        end
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
             app.board = repmat(" ", 3, 3);
    app.GameActive = false;
    app.currentPlayer = 'X';
    app.Label.Text = 'Start New Game ';% Clear button texts
    app.Label2.Text = ' ';% Clear button texts
    for i = 1:9
        app.(['Button_', num2str(i)]).Text =" ";
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Button_1 = uibutton(app.GridLayout, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Tag = 'btn1';
            app.Button_1.Text = '';
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Tag = 'btn2';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.GridLayout, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Tag = 'btn3';
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.GridLayout, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Tag = 'btn4';
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.GridLayout, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Tag = 'btn5';
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.GridLayout, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Tag = 'btn6';
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.GridLayout, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Tag = 'btn7';
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.GridLayout, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Tag = 'btn8';
            app.Button_8.Text = '';
            app.Button_9 = uibutton(app.GridLayout, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Tag = 'btn9';
            app.Button_9.Text = '';
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '                       ';
            app.Label2 = uilabel(app.GridLayout);
            app.Label2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TicTacToe_Game

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end