classdef tictactoe < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        GridLayout   matlab.ui.container.GridLayout
        Label_2      matlab.ui.control.Label
        Label        matlab.ui.control.Label
        ResetButton  matlab.ui.control.Button
        Button_9     matlab.ui.control.Button
        Button_8     matlab.ui.control.Button
        Button_7     matlab.ui.control.Button
        Button_6     matlab.ui.control.Button
        Button_5     matlab.ui.control.Button
        Button_4     matlab.ui.control.Button
        Button_3     matlab.ui.control.Button
        Button_2     matlab.ui.control.Button
        Button_1     matlab.ui.control.Button
    end

properties (Access = private)
    Board = repmat(' ', 3, 3); % 3x3 matrix for the board
    PlayerSymbol = 'X';       % Player symbol
    ComputerSymbol = 'O';     % Computer symbol
    GameActive = true;        % To track if the game is ongoing
end
    
    methods (Access = private)    
    function playerMove(app, row, col, button)
    if ~app.GameActive || app.Board(row, col) ~= ' '
        return; % Ignore invalid moves
    end

    app.Board(row, col) = app.PlayerSymbol;
    if app.PlayerSymbol =="X"
       button.FontColor = [1.00,0.70,0.70];
    end
    button.Text = app.PlayerSymbol;

    if app.checkWinner(app.PlayerSymbol)
        app.Label.Text = 'You Win!';
        app.Label_2.Text = sprintf("Hurray 🎉")
        app.GameActive = false;
        return;
    elseif all(app.Board ~= ' ', 'all')
        app.Label.Text = 'Match Drawn...';
        app.GameActive = false;
        return;
    end

    app.computerMove();
end

        
       function computerMove(app)
    [row, col] = app.findBestMove(); 
    app.Board(row, col) = app.ComputerSymbol;
    button = app.getButton(row, col); % Find the button for this move
    button.FontColor = [0.35,0.70,1.00];
    button.Text = app.ComputerSymbol;

    if app.checkWinner(app.ComputerSymbol)
        app.Label.Text = 'Computer Wins!';
        app.Label_2.Text = sprintf("Oops 😞");
        app.GameActive = false;
    elseif all(app.Board ~= ' ', 'all')
        app.Label.Text = 'Match Drawn...';
        app.GameActive = false;
    else
        app.Label.Text = 'Your Turn!';
    end
end

       function won = checkWinner(app, symbol)
    won = any(all(app.Board == symbol, 1)) || ... % Rows
          any(all(app.Board == symbol, 2)) || ... % Columns
          all(diag(app.Board) == symbol) || ...   % Main diagonal
          all(diag(flipud(app.Board)) == symbol); % Anti-diagonal
end

        
        function button = getButton(app, row, col)
    buttons = [app.Button_1, app.Button_2, app.Button_3;
               app.Button_4, app.Button_5, app.Button_6;
               app.Button_7, app.Button_8, app.Button_9];
    button = buttons(row, col);
        end
        function [row, col] = findBestMove(app)
    [rows, cols] = find(app.Board == ' ');
    idx = randi(numel(rows));
    row = rows(idx);
    col = cols(idx);
end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
    app.Board = repmat(' ', 3, 3);
    app.GameActive = true;
    app.Label.Text = 'Your Turn!';
    
    % Reset all button texts
    buttons = [app.Button_1, app.Button_2, app.Button_3;
               app.Button_4, app.Button_5, app.Button_6;
               app.Button_7, app.Button_8, app.Button_9];
    for i = 1:numel(buttons)
        buttons(i).Text =" ";
    end
    app.Label_2.Text = sprintf(" ");
        
        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
             app.playerMove(1, 1, app.Button_1); % Row 1, Column 1
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.playerMove(1, 2, app.Button_2); % Row 1, Column 2
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.playerMove(1, 3, app.Button_3); % Row 1, Column 3
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.playerMove(2, 1, app.Button_4); % Row 2, Column 1
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.playerMove(2, 2, app.Button_5); % Row 2, Column 2
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.playerMove(2, 3, app.Button_6); % Row 2, Column 3
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            app.playerMove(3, 1, app.Button_7); % Row 3, Column 1
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            app.playerMove(3, 2, app.Button_8); % Row 3, Column 2
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
         app.playerMove(3, 3, app.Button_9); % Row 3, Column 3
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Button_1 = uibutton(app.GridLayout, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '';
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.GridLayout, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.GridLayout, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.GridLayout, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.GridLayout, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.GridLayout, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.GridLayout, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '';
            app.Button_9 = uibutton(app.GridLayout, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '';
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tictactoe

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end